classdef simulation_app_v_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GraphicsTab                   matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        MaterialTab                   matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        ProgressLamp                  matlab.ui.control.Lamp
        ProgressLampLabel             matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
        SimulationPanel               matlab.ui.container.Panel
        StressesButton                matlab.ui.control.Button
        DeformationButton             matlab.ui.control.Button
        RunSimulationButton           matlab.ui.control.Button
        MeshsizemmEditField           matlab.ui.control.NumericEditField
        MeshsizemmEditFieldLabel      matlab.ui.control.Label
        GenerateMeshButton            matlab.ui.control.Button
        HydrostaticSimulationLabel    matlab.ui.control.Label
        BoundaryConditionPanel        matlab.ui.container.Panel
        BoundaryConditionButtonGroup  matlab.ui.container.ButtonGroup
        HydroPressureButton           matlab.ui.control.ToggleButton
        FixedButton                   matlab.ui.control.ToggleButton
        DirectionDropDown             matlab.ui.control.DropDown
        DirectionDropDownLabel        matlab.ui.control.Label
        PressurePaEditField           matlab.ui.control.NumericEditField
        PressurePaEditFieldLabel      matlab.ui.control.Label
        ForceNEditField               matlab.ui.control.NumericEditField
        ForceNEditFieldLabel          matlab.ui.control.Label
        FacesListBox                  matlab.ui.control.ListBox
        FacesListBoxLabel             matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        FileNameLabel                 matlab.ui.control.Label
        SummaryTextArea               matlab.ui.control.TextArea
        SummaryTextAreaLabel          matlab.ui.control.Label
        FileuploadButton              matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        new_x % Description
        new_y
        new_z
        face_name
        stlFile
        smodel
        selectedFace
        BCvalue
        Rs
        MeshValue
        BCButton
        fixedface
        forceface
        hydroface
        Force_value
        direction
        Directionvalue
        Pressure_value
    end

    
    methods (Access = private)
        
        function BC_Apply(app)
            %cond = app.DirectionDropDown.ValueChangedFcn;
            app.direction = app.Directionvalue;
            face = str2double(app.selectedFace);
            

            %app.SummaryTextArea.Value = sprintf('direction is %s',app.direction);
            
            if (app.BCButton.Text == "Fixed")
                app.fixedface = face;
                app.SummaryTextArea.Value = sprintf('selected fixed face is %d and condition is fixed \n',app.fixedface);


            elseif (app.BCButton.Text == "Force")
                app.forceface = face;
                force = app.Force_value;
                app.SummaryTextArea.Value = sprintf('force %d on selected face %d \n', force, app.forceface);

            elseif (app.BCButton.Text == "Hydro Pressure")
                app.hydroface = face;
                app.SummaryTextArea.Value = sprintf('selected Hydrostatic pressure on face is %d \n',app.hydroface, app.direction);

            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.MeshValue = double(1);
         app.direction = 'z';
         app.BCButton.Text = "Fixed";
         app.PressurePaEditField.Enable = "off";
         app.ForceNEditField.Enable = "off";
         app.DirectionDropDown.Enable = "off";
         

        end

        % Button pushed function: FileuploadButton
        function FileuploadButtonPushed(app, event)
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            drawnow();
            
            [filename, pathname] = uigetfile({'*.stl'},'File Selector');

            if ~isequal(filename,0)
                app.stlFile = fullfile(pathname,filename);
                bulgemodel = createpde('structural','static-solid');
                importGeometry(bulgemodel,app.stlFile);
                
                [F,~] = stlread(app.stlFile);

                % Do something with F and V   
                x=F.Points(:,1);
                y=F.Points(:,2);
                z=F.Points(:,end);

                
                min_x=min(x); min_y=min(y); min_z=min(z);
                max_x=max(x); max_y=max(y); max_z=max(z);

                app.face_name = bulgemodel.Geometry.cellFaces(1);

                app.FileNameLabel.Text = filename;
                app.SummaryTextArea.Value = sprintf(['File uploaded successfully!\n' ...
                'Bounding Box in x, y and z direction:\n'...
                'X direction: minimum %f and maximum %f\n' ...
                'Y direction: minimum %f and maximum %f\n' ...
                'Z direction: minimum %f and maximum %f\n'],...
                min_x,max_x,min_y,max_y,min_z,max_z); 
                
                app.ApplyButton.Enable = "on";
                app.smodel = bulgemodel;
                
                app.ProgressLamp.Color = [1.00,1.00,1.00];
            else
                app.SummaryTextArea.Value = 'File upload failed.';
                app.FileNameLabel.Text = "File Name";
                app.ApplyButton.Enable = "off";

                app.ProgressLamp.Color = [1.00,1.00,1.00];
            return;

            
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            drawnow();
            
            BC_Apply(app);

            app.ProgressLamp.Color = [1.00,1.00,1.00];
        end

        % Value changed function: FacesListBox
        function FacesListBoxValueChanged(app, event)
            
            app.selectedFace = app.FacesListBox.Value;
            

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            drawnow();
            
            origState = app.UIFigure.HandleVisibility; 
            handleVisCleanup = onCleanup(@()set(app.UIFigure,'HandleVisibility',origState));
            app.UIFigure.HandleVisibility = 'on'; 
            
            set(groot, 'CurrentFigure', app.UIFigure)
            set(app.UIFigure,'CurrentAxes',app.UIAxes)
            pdegplot(app.smodel,"FaceLabels","on","FaceAlpha",0.5);
            
            app.FacesListBox.Items = string(app.face_name);

            app.ProgressLamp.Color = [1.00,1.00,1.00];
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            app.Directionvalue = app.DirectionDropDown.Value;
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            drawnow();
            
            E = 2e9; % Young's modulus (Pa)
            nu = 0.3; % Poisson's ratio
            structuralProperties(app.smodel, 'YoungsModulus', E, 'PoissonsRatio', nu);
            
            
            structuralBC(app.smodel,'Face', app.fixedface,'Constraint','fixed');
            
            function pressure = myfunPressure(location,rho)
              g = 9.81; % acceleration due to gravity (m/s^2)

              % Extract the z-coordinate from the location structure
              h = location.(app.direction); % height is the z-coordinate
              %h = location.z;
              pressure = rho * g * h; % hydrostatic pressure
            end
            rho = 1000; % fluid density (for water it's approximately 1000 kg/m^3)

            structuralBoundaryLoad(app.smodel, 'Face', app.hydroface, "Pressure", @(location,state)myfunPressure(location,rho));
            app.Rs = solve(app.smodel);
            app.SummaryTextArea.Value = sprintf('Simulation Completed!');

            app.ProgressLamp.Color = [1.00,1.00,1.00];
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            drawnow();
            generateMesh(app.smodel,"Hmax",app.MeshValue);

            origState = app.UIFigure.HandleVisibility; 
            handleVisCleanup = onCleanup(@()set(app.UIFigure,'HandleVisibility',origState));
            app.UIFigure.HandleVisibility = 'on'; 
            
            set(groot, 'CurrentFigure', app.UIFigure)
            set(app.UIFigure,'CurrentAxes',app.UIAxes)
            
            pdeplot3D(app.smodel);
            app.ProgressLamp.Color = [1.00,1.00,1.00];

        end

        % Value changed function: MeshsizemmEditField
        function MeshsizemmEditFieldValueChanged(app, event)
            app.MeshValue = app.MeshsizemmEditField.Value;
            
        end

        % Button pushed function: StressesButton
        function StressesButtonPushed(app, event)
            pdeplot3D(app.smodel,"ColorMapData",app.Rs.VonMisesStress,"Deformation",app.Rs.Displacement,"DeformationScaleFactor",1);
        end

        % Button pushed function: DeformationButton
        function DeformationButtonPushed(app, event)
            pdeplot3D(app.smodel,"ColorMapData",app.Rs.Displacement.Magnitude,"Deformation",app.Rs.Displacement,"DeformationScaleFactor",1);
        end

        % Selection changed function: BoundaryConditionButtonGroup
        function BoundaryConditionButtonGroupSelectionChanged(app, event)
            app.BCButton = app.BoundaryConditionButtonGroup.SelectedObject;
            if (app.BCButton.Text == "Fixed")
                app.PressurePaEditField.Enable = "off";
                app.ForceNEditField.Enable = "off";
                app.DirectionDropDown.Enable = "off";

            elseif (app.BCButton.Text == "Force")
                app.PressurePaEditField.Enable = "off";
                app.ForceNEditField.Enable = "on";
                app.DirectionDropDown.Enable = "on";

            elseif (app.BCButton.Text == "Hydro Pressure")
                app.PressurePaEditField.Enable = "off";
                app.ForceNEditField.Enable = "off";
                app.DirectionDropDown.Enable = "on";
            end
            
        end

        % Value changed function: ForceNEditField
        function ForceNEditFieldValueChanged(app, event)
            app.Force_value = app.ForceNEditField.Value;
            
        end

        % Value changed function: PressurePaEditField
        function PressurePaEditFieldValueChanged(app, event)
            app.Pressure_value = app.PressurePaEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileuploadButton = uibutton(app.UIFigure, 'push');
            app.FileuploadButton.ButtonPushedFcn = createCallbackFcn(app, @FileuploadButtonPushed, true);
            app.FileuploadButton.Text = 'File upload';
            app.SummaryTextAreaLabel = uilabel(app.UIFigure);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.UIFigure);
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.BoundaryConditionPanel = uipanel(app.UIFigure);
            app.BoundaryConditionPanel.Title = 'Boundary Condition';
            app.ApplyButton = uibutton(app.BoundaryConditionPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.FacesListBoxLabel = uilabel(app.BoundaryConditionPanel);
            app.FacesListBoxLabel.Text = 'Faces';
            app.FacesListBox = uilistbox(app.BoundaryConditionPanel);
            app.FacesListBox.Items = {'1', '2', '3', '4', '5'};
            app.FacesListBox.Multiselect = 'on';
            app.FacesListBox.ValueChangedFcn = createCallbackFcn(app, @FacesListBoxValueChanged, true);
            app.FacesListBox.Value = {'1'};
            app.ForceNEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.ForceNEditFieldLabel.Text = 'Force (N)';
            app.ForceNEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.ForceNEditField.ValueChangedFcn = createCallbackFcn(app, @ForceNEditFieldValueChanged, true);
            app.PressurePaEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.PressurePaEditFieldLabel.Text = 'Pressure (Pa)';
            app.PressurePaEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.PressurePaEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePaEditFieldValueChanged, true);
            app.DirectionDropDownLabel = uilabel(app.BoundaryConditionPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.BoundaryConditionPanel);
            app.DirectionDropDown.Items = {' ', 'x', 'y', 'z'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = ' ';
            app.BoundaryConditionButtonGroup = uibuttongroup(app.BoundaryConditionPanel);
            app.BoundaryConditionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryConditionButtonGroupSelectionChanged, true);
            app.BoundaryConditionButtonGroup.Title = 'Boundary Condition';
            app.FixedButton = uitogglebutton(app.BoundaryConditionButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.HydroPressureButton = uitogglebutton(app.BoundaryConditionButtonGroup);
            app.HydroPressureButton.Text = 'Hydro Pressure';
            app.HydrostaticSimulationLabel = uilabel(app.UIFigure);
            app.HydrostaticSimulationLabel.Text = 'Hydrostatic Simulation';
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.GenerateMeshButton = uibutton(app.SimulationPanel, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.MeshsizemmEditFieldLabel = uilabel(app.SimulationPanel);
            app.MeshsizemmEditFieldLabel.Text = 'Mesh size(mm)';
            app.MeshsizemmEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.MeshsizemmEditField.Limits = [0 Inf];
            app.MeshsizemmEditField.ValueChangedFcn = createCallbackFcn(app, @MeshsizemmEditFieldValueChanged, true);
            app.MeshsizemmEditField.Value = 1;
            app.RunSimulationButton = uibutton(app.SimulationPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.DeformationButton = uibutton(app.SimulationPanel, 'push');
            app.DeformationButton.ButtonPushedFcn = createCallbackFcn(app, @DeformationButtonPushed, true);
            app.DeformationButton.Text = 'Deformation';
            app.StressesButton = uibutton(app.SimulationPanel, 'push');
            app.StressesButton.ButtonPushedFcn = createCallbackFcn(app, @StressesButtonPushed, true);
            app.StressesButton.Text = 'Stresses';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ProgressLampLabel = uilabel(app.UIFigure);
            app.ProgressLampLabel.Text = 'Progress';
            app.ProgressLamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphicsTab = uitab(app.TabGroup);
            app.GraphicsTab.Title = 'Graphics';
            app.UIAxes = uiaxes(app.GraphicsTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.UITable = uitable(app.MaterialTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_app_v_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end